classdef TurtlebotApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        BASICMODECheckBox     matlab.ui.control.CheckBox
        ADVANCEDMODECheckBox  matlab.ui.control.CheckBox
        Label_2               matlab.ui.control.Label
        STOPButton            matlab.ui.control.StateButton
        STARTButton           matlab.ui.control.StateButton
        STATUSLabel           matlab.ui.control.Label
        Label                 matlab.ui.control.Label
        Panel                 matlab.ui.container.Panel
        Panel_2               matlab.ui.container.Panel
        COLOURListBox         matlab.ui.control.ListBox
        COLOURListBoxLabel    matlab.ui.control.Label
        Image                 matlab.ui.control.Image
    end

    
    properties (Access = public)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.HandleVisibility = 'on';
        end

        % Value changed function: STARTButton
        function STARTButtonValueChanged(app, event)
            value = app.STARTButton.Value;
           
            if value == 1
                app.STOPButton.Value = 0;
                app.STATUSLabel.Text = 'RUNNING';
                
                if app.ADVANCEDMODECheckBox.Value == 1
                    mode = "advanced";
                end
                
                if app.BASICMODECheckBox.Value == 1
                    mode = "basic";
                end
                
                   
                TurtleBotTask(mode, app.COLOURListBox.Value);
            end
            
        end

        % Value changed function: STOPButton
        function STOPButtonValueChanged(app, event)
            value = app.STOPButton.Value;
            if value == 1
                app.STARTButton.Value = 0;
                app.STATUSLabel.Text = 'STOPPED';
            end
        end

        % Value changed function: ADVANCEDMODECheckBox
        function ADVANCEDMODECheckBoxValueChanged(app, event)
            value = app.ADVANCEDMODECheckBox.Value;
            if value == 1
                app.BASICMODECheckBox.Value = 0;
            end
        end

        % Value changed function: BASICMODECheckBox
        function BASICMODECheckBoxValueChanged(app, event)
            value = app.BASICMODECheckBox.Value;
            if value == 1
                app.ADVANCEDMODECheckBox.Value = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Image = uiimage(app.Panel_2);
            app.Image.ImageSource = 'heart.jpg';
            app.COLOURListBoxLabel = uilabel(app.Panel_2);
            app.COLOURListBoxLabel.Text = 'COLOUR:';
            app.COLOURListBox = uilistbox(app.Panel_2);
            app.COLOURListBox.Items = {'RED', 'GREEN', 'BLUE'};
            app.COLOURListBox.Value = 'RED';
            app.Panel = uipanel(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'STATUS:';
            app.STATUSLabel = uilabel(app.UIFigure);
            app.STATUSLabel.Text = 'STOPPED';
            app.STARTButton = uibutton(app.UIFigure, 'state');
            app.STARTButton.ValueChangedFcn = createCallbackFcn(app, @STARTButtonValueChanged, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.UIFigure, 'state');
            app.STOPButton.ValueChangedFcn = createCallbackFcn(app, @STOPButtonValueChanged, true);
            app.STOPButton.Text = 'STOP';
            app.STOPButton.Value = true;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {'Sensors and Control (SPR2022) - Assessment Task 3 '; 'Group Project 7:  Turtlebot robot following each other'; ' '; ' By: Elmer Junior Caballero, Jithin Abraham, Shayer Shah'};
            app.ADVANCEDMODECheckBox = uicheckbox(app.UIFigure);
            app.ADVANCEDMODECheckBox.ValueChangedFcn = createCallbackFcn(app, @ADVANCEDMODECheckBoxValueChanged, true);
            app.ADVANCEDMODECheckBox.Text = ' ADVANCED MODE';
            app.ADVANCEDMODECheckBox.Value = true;
            app.BASICMODECheckBox = uicheckbox(app.UIFigure);
            app.BASICMODECheckBox.ValueChangedFcn = createCallbackFcn(app, @BASICMODECheckBoxValueChanged, true);
            app.BASICMODECheckBox.Text = ' BASIC MODE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TurtlebotApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end